classdef QuailKit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuailkitUIFigure   matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        ImportMenu         matlab.ui.container.Menu
        SettingsMenu       matlab.ui.container.Menu
        GridLayout2        matlab.ui.container.GridLayout
        SpectrogramsPanel  matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        Button             matlab.ui.control.Button
        Button_2           matlab.ui.control.Button
        Button_3           matlab.ui.control.Button
        Button_4           matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes_3           matlab.ui.control.UIAxes
        UIAxes_4           matlab.ui.control.UIAxes
        Panel              matlab.ui.container.Panel
        ModeSwitchLabel    matlab.ui.control.Label
        ModeSwitch         matlab.ui.control.Switch
        MapSwitchLabel     matlab.ui.control.Label
        MapSwitch          matlab.ui.control.Switch
    end

    
    properties (Access = public)
        %General Properties
        dataLoaded = false% Spectrograms have been loaded.
        dataPath    %File directory containing the data.
        micPaths = []
        
        %Audio Data
        audioSamples %object holding all the audio samples.
        AudioFileSpec
        AudioFilePlay
        TS
        Fs %Sampling Frequency
        F = 1000:10:3000;
        wind = 720;
        noverlap = 576;     %round(0.8*wind);
        
        %Online Properties
        loadIntervalRate = 300 %Loads 5 minutes from disk
        loadSubIntervalRate = 10 %Brings in 10 seconds at a time
        
        %Holds the sample intervals
        initialLoadInterval 
        initialSubInterval 
        
        loadInterval    %includes lower and upper bound of 5 minute interval of audio samples  : [lb ub]
        subInterval     %includes lower and upper bound of 10 second interval of audio samples : [lb ub]
        %%
        curLoadInterval %if this value is 1 it's in the first 5 minute interval
        curSubInterval %if this value is 1 it's in the first 10 second interval
        
        Samples %Amount of samples in the entire recording
        SubSamples %Amount of samples in 10 seconds
    end
    
    methods (Access = private)
        
        function UpdateAudio(app,c) %These variable changes should be correct, but i will need to double check.
            %I switched boy and aoy first because if i change a first, a is
            %the substring of aoy, so it doesn't work nicely.
            
            %Check if algorithm should go to the next x interval
            if (app.curSubInterval+c)*app.initialSubInterval(2) > app.SubSamples || app.curSubInterval+c <= 0
               if app.curSubInterval+c == 0
                    app.curLoadInterval = app.curLoadInterval - 1;
               elseif (app.initialLoadInterval (2)/app.initialSubInterval(2)) == app.curSubInterval + c
                   app.curLoadInterval = app.curLoadInterval+1;
               else
                   app.curLoadInterval = app.curLoadInterval+floor((app.curSubInterval+c)/(app.initialLoadInterval (2)/app.initialSubInterval(2)));
               end
               
               if app.curLoadInterval == 1
                   app.loadInterval  = app.initialLoadInterval ;
                   app.curSubInterval = app.initialLoadInterval (2)/app.initialSubInterval(2)+1;
                   app.subInterval = app.initialSubInterval(2)*[app.curSubInterval+c-1 app.curSubInterval+c];
               else
                   if app.curLoadInterval <= app.Samples
                   app.loadInterval  = app.initialLoadInterval (2)*[app.curLoadInterval-1 app.curLoadInterval];
                   else
                   app.loadInterval  = [app.initialLoadInterval (2)*(app.curLoadInterval-1) app.Samples];
                   end
                   rema = mod(app.curSubInterval + c,(app.initialLoadInterval (2)/app.initialSubInterval(2)));
                   if rema == 0
                       app.curSubInterval = 1;
                       app.subInterval = app.initialSubInterval;
                   else
                       app.curSubInterval = 1 + rema;
                       app.subInterval = app.initialSubInterval(2)*[app.curSubInterval-1 app.curSubInterval];
                   end
               end
               
               app = HTdataAccess(app,'read');
               
            %If the next y interval exists in the current x interval
            else 
                %Going to the beginning
                if app.curSubInterval+c == 1 
                    app.subInterval = app.initialSubInterval;
                %Next y interval is too big for the current x interval.
                elseif (app.curSubInterval+c)*app.initialSubInterval(2) > app.SubSamples 
                    app.subInterval = [(app.curSubInterval+c)*app.initialSubInterval(2) app.Samples];
                else
                    app.subInterval = app.initialSubInterval(2)*[app.curSubInterval+c-1 app.curSubInterval+c]; %
                end
                app.curSubInterval = app.curSubInterval + c;
            end
        end
        
        function [s,t] = FBspectrogram(app,i)
            [s,~,t] = spectrogram(app.AudioFilePlay(app.subInterval(1):app.subInterval(2),i),app.wind,app.noverlap,app.F,app.Fs);
            
            s= db(abs(s));
            %Here we should add the 10s that we are in to "t"
%            t=t-t(1)+app.TS.Time(app.subInterval(1):app.subInterval(2));
            t = t-t(1);
            %f = 1+max(max(s))*[1,1];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: SpectrogramsPanel
        function SpectrogramsPanelSizeChanged(app, event)
            position = app.SpectrogramsPanel.Position;
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            
        end

        % Value changed function: MapSwitch
        function MapSwitchValueChanged(app, event)
            if strcmp(app.ModeSwitch.Value,"Online")
                if strcmp(app.MapSwitch.Value,'On')
                    x= 1:0.01:4*pi;
                    gx = geoaxes('parent',app.Panel)
                    geoplot(gx,33.35,-101.012,'o','MarkerSize',5,'MarkerFaceColor','b');
                    geobasemap(gx, 'satellite')
                else
                    delete(app.Panel.Children)
                end
             end
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            if strcmp(app.ModeSwitch.Value,"Online") && app.dataLoaded
                title = ["","","",""];
                if ~isempty(app.micPaths)
                    for i = 1:4     % 4 = length(app.micPaths)
                        name = split(app.micPaths(i).name,'_');
                        title(i) = name{1};
                    end
                    app.UIAxes.Title.String = title(1);
                    app.UIAxes_2.Title.String = title(2);
                    app.UIAxes_3.Title.String = title(3);
                    app.UIAxes_4.Title.String = title(4);
                end
                
                f = cell(1,4); t= cell(1,4);
                for i = 1:4
                    [f{i} ,t{i}] = FBspectrogram(app,i);
                end
                
                imagesc(app.UIAxes,t{1},app.F,f{1})
                imagesc(app.UIAxes_2,t{2},app.F,f{2})
                imagesc(app.UIAxes_3,t{3},app.F,f{3})
                imagesc(app.UIAxes_4,t{4},app.F,f{4})
            else
                delete(app.Panel.Children)
                for i = 5:8
                    cla(app.SpectrogramsPanel.Children.Children(i))
                end
                app.MapSwitch.Value = 'Off';
            end
        end

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            app.dataPath = uigetdir('','Select Recordings Folder'); %Prompt user for a directory
            if exist(fullfile(app.dataPath,"Mics")) == 7 %Check if Mics is a subdirectory
                app = HTdataAccess(app,'read');
                if ~isnan(app.audioSamples)
                    app.dataLoaded = true;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuailkitUIFigure = uifigure('Visible', 'off');
            app.QuailkitUIFigure.Name = 'Quailkit';
            app.FileMenu = uimenu(app.QuailkitUIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.SettingsMenu = uimenu(app.FileMenu);
            app.SettingsMenu.Text = 'Settings';
            app.GridLayout2 = uigridlayout(app.QuailkitUIFigure);
            app.SpectrogramsPanel = uipanel(app.GridLayout2);
            app.SpectrogramsPanel.Title = 'Spectrograms';
            app.SpectrogramsPanel.SizeChangedFcn = createCallbackFcn(app, @SpectrogramsPanelSizeChanged, true);
            app.GridLayout = uigridlayout(app.SpectrogramsPanel);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.Icon = 'play.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.Icon = 'play.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.Icon = 'play.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.Icon = 'play.png';
            app.Button_4.Text = '';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Frequency')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, 'Frequency')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, 'Frequency')
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'Time')
            ylabel(app.UIAxes_4, 'Frequency')
            app.Panel = uipanel(app.GridLayout2);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.ModeSwitchLabel = uilabel(app.GridLayout2);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.GridLayout2, 'slider');
            app.ModeSwitch.Items = {'Offline', 'Online'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Offline';
            app.MapSwitchLabel = uilabel(app.GridLayout2);
            app.MapSwitchLabel.Text = 'Map';
            app.MapSwitch = uiswitch(app.GridLayout2, 'slider');
            app.MapSwitch.ValueChangedFcn = createCallbackFcn(app, @MapSwitchValueChanged, true);
            % Show the figure after all components are created
            app.QuailkitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuailKit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuailkitUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuailkitUIFigure)
        end
    end
end